classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaxtargetspeedEditField         matlab.ui.control.NumericEditField
        MaxtargetspeedEditFieldLabel    matlab.ui.control.Label
        TargetspeedmEditField           matlab.ui.control.NumericEditField
        TargetspeedmEditFieldLabel      matlab.ui.control.Label
        MaxsafedistanceEditField        matlab.ui.control.NumericEditField
        MaxsafedistanceEditFieldLabel   matlab.ui.control.Label
        SafedistancemEditField          matlab.ui.control.NumericEditField
        SafedistancemEditFieldLabel     matlab.ui.control.Label
        MaxinitialvehiclespeedEditField  matlab.ui.control.NumericEditField
        MaxinitialvehiclespeedEditFieldLabel  matlab.ui.control.Label
        InitialvehiclespeedmsEditField  matlab.ui.control.NumericEditField
        InitialvehiclespeedmsEditFieldLabel  matlab.ui.control.Label
        NumberofsumilationsEditField    matlab.ui.control.NumericEditField
        NumberofsumilationsEditFieldLabel  matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        %% Sim models
        simMdl          % Base sim model
        simMdlFaulty    % Sim model with FIBlock

        %% Variable params
        % Initial condition for the ego car
        v0_ego          % Initial speed of the ego car           (m/s)
        v0_range
        % Linear model for ACC design
        %% Automatic Cruise Control (ACC) Controller Parameters
        v_set           % ACC set speed                         (m/s)
        v_set_range
        default_spacing % ACC default spacing                   (m)
        default_spacing_range
    end

    methods (Access = private)

        function r = checkEditFields(app)
            r = false;
            if app.InitialvehiclespeedmsEditField.Value > app.MaxinitialvehiclespeedEditField.Value ...
                    || app.TargetspeedmEditField.Value > app.MaxtargetspeedEditField.Value ...
                    || app.SafedistancemEditField.Value > app.MaxsafedistanceEditField.Value
                uialert(app.UIFigure, "Max input values must be higher than min input values", "Invalid inputs")
                r=true;
            end
        end

        function vals = generarateValues(app)
            simNum = app.NumberofsumilationsEditField.Value;
            vals = randi(simNum, [simNum, 3]);
            vals(:, 1) = rescale(vals(:, 1), app.InitialvehiclespeedmsEditField.Value, app.MaxinitialvehiclespeedEditField.Value);
            vals(:, 2) = rescale(vals(:, 2), app.TargetspeedmEditField.Value, app.MaxtargetspeedEditField.Value);
            vals(:, 3) = rescale(vals(:, 3), app.SafedistancemEditField.Value, app.MaxsafedistanceEditField.Value);
        end

        function multiSimulate(app, randVals)
            for i = 1:app.NumberofsumilationsEditField.Value
                app.v0_ego = randVals(i, 1);
                app.v_set = randVals(i, 2);
                app.default_spacing = randVals(i, 3);

                app.assignVars()
                sim(app.simMdl)
                helperPlotACCResults(logsout,app.default_spacing,evalin("base", "time_gap"))
            end
        end

        function assignVars(app)
            assignin("base", "v0_ego", app.v0_ego)
            assignin("base", "v_set", app.v_set)
            assignin("base", "default_spacing", app.default_spacing)
            m = evalin("base", "m");
            Iz = evalin("base", "Iz");
            lf = evalin("base", "lf");
            lr = evalin("base", "lr");
            Cf = evalin("base", "Cf");
            Cr = evalin("base", "Cr");
            tau = evalin("base", "tau");
            assignin("base", "G", helperACCLinearModel(m,Iz,lf,lr,Cf,Cr,tau,app.v0_ego))
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simMdl = "ACCTestBenchExample";
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            wasModelLoaded = bdIsLoaded(app.simMdl);
            if ~wasModelLoaded
                load_system(app.simMdl)
            end            
            if app.NumberofsumilationsEditField.Value == 1
                evalin("base", "helperACCSetUp")
                app.v0_ego = app.InitialvehiclespeedmsEditField.Value;
                app.v_set = app.TargetspeedmEditField.Value;
                app.default_spacing = app.SafedistancemEditField.Value;
                app.assignVars()
                sim(app.simMdl);
                helperPlotACCResults(logsout,app.default_spacing,evalin("base", "time_gap"))
            else
                if ~app.checkEditFields()
                    evalin("base", "helperACCSetUp")
                    randVals = app.generarateValues();
                    app.multiSimulate(randVals)
                end
            end
            if wasModelLoaded
                close_system(app.simMdl)
            end
        end

        % Value changed function: NumberofsumilationsEditField
        function NumberofsumilationsEditFieldValueChanged(app, event)
            value = app.NumberofsumilationsEditField.Value;
            if value > 1
                app.MaxinitialvehiclespeedEditField.Editable = "on";
                app.MaxsafedistanceEditField.Editable = "on";
                app.MaxtargetspeedEditField.Editable = "on";

                app.TargetspeedmEditFieldLabel.Text = "Min target speed (m/s)";
                app.InitialvehiclespeedmsEditFieldLabel.Text = "Min initial speed (m/s)";
                app.SafedistancemEditFieldLabel.Text = "Min safe distance (m)";
            else
                app.MaxinitialvehiclespeedEditField.Editable = "off";
                app.MaxsafedistanceEditField.Editable = "off";
                app.MaxtargetspeedEditField.Editable = "off";
                app.TargetspeedmEditFieldLabel.Text = "Target speed (m/s)";
                app.InitialvehiclespeedmsEditFieldLabel.Text = "Initial speed (m/s)";
                app.SafedistancemEditFieldLabel.Text = "Safe distance (m/s)";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.NumberofsumilationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofsumilationsEditFieldLabel.Text = 'Number of sumilations';
            app.NumberofsumilationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofsumilationsEditField.Limits = [1 Inf];
            app.NumberofsumilationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsumilationsEditFieldValueChanged, true);
            app.NumberofsumilationsEditField.Value = 1;
            app.InitialvehiclespeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialvehiclespeedmsEditFieldLabel.Text = 'Initial vehicle speed (m/s)';
            app.InitialvehiclespeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialvehiclespeedmsEditField.Limits = [1 Inf];
            app.InitialvehiclespeedmsEditField.Value = 20.6;
            app.MaxinitialvehiclespeedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxinitialvehiclespeedEditFieldLabel.Text = 'Max initial vehicle speed';
            app.MaxinitialvehiclespeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxinitialvehiclespeedEditField.Limits = [1 Inf];
            app.MaxinitialvehiclespeedEditField.Editable = 'off';
            app.MaxinitialvehiclespeedEditField.Value = 1;
            app.SafedistancemEditFieldLabel = uilabel(app.UIFigure);
            app.SafedistancemEditFieldLabel.Text = 'Safe distance (m)';
            app.SafedistancemEditField = uieditfield(app.UIFigure, 'numeric');
            app.SafedistancemEditField.Limits = [1 Inf];
            app.SafedistancemEditField.Value = 5;
            app.MaxsafedistanceEditFieldLabel = uilabel(app.UIFigure);
            app.MaxsafedistanceEditFieldLabel.Text = 'Max safe distance';
            app.MaxsafedistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxsafedistanceEditField.Limits = [1 Inf];
            app.MaxsafedistanceEditField.Editable = 'off';
            app.MaxsafedistanceEditField.Value = 1;
            app.TargetspeedmEditFieldLabel = uilabel(app.UIFigure);
            app.TargetspeedmEditFieldLabel.Text = 'Target speed (m)';
            app.TargetspeedmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetspeedmEditField.Limits = [1 Inf];
            app.TargetspeedmEditField.Value = 21.5;
            app.MaxtargetspeedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxtargetspeedEditFieldLabel.Text = 'Max target speed';
            app.MaxtargetspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxtargetspeedEditField.Limits = [1 Inf];
            app.MaxtargetspeedEditField.Editable = 'off';
            app.MaxtargetspeedEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end